classdef data_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRacing2024DataVisualizerUIFigure  matlab.ui.Figure
        Hyperlink           matlab.ui.control.Hyperlink
        vars_hdr            matlab.ui.control.Label
        log_file_hdr        matlab.ui.control.Label
        version_lbl         matlab.ui.control.Label
        lapB_edit           matlab.ui.control.NumericEditField
        LapBLabel           matlab.ui.control.Label
        lapA_edit           matlab.ui.control.NumericEditField
        Lap1Label           matlab.ui.control.Label
        title               matlab.ui.control.Label
        mracing_img         matlab.ui.control.Image
        upload_log_btn      matlab.ui.control.Button
        reformat_file_chk   matlab.ui.control.CheckBox
        file_lbl            matlab.ui.control.Label
        run_visualizer_btn  matlab.ui.control.Button
    end

    
    properties (Access = private)
        file = ""
        location = ""
        data

        lap_min % Smallest lap number (should be 0?)
        lap_max % Biggest lap number
        lapA % Lap 1 number
        lapA_t % Lap 1 time
        lapB % Lap 2 number
        lapB_t % Lap 2 time
        lapf % Fast lap number
        lapf_t % Fast lap time

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: run_visualizer_btn
        function run_visualizer(app, event)
            w = waitbar(0, "Reading data...");
            
            %% Reformat file
            if get(app.reformat_file_chk, 'Value') == 1

                % These are the variables we want to keep in the reformatted file
                % Make sure 'variables_select' has no carriage returns (\r)!!!!
                relevant_vars = fileread('variables_relevant.txt');
                relevant_vars = strsplit(relevant_vars, '\n');
                
                % readtable replaces [ ] / with _ and removes whitespace
                relevant_vars = strrep(relevant_vars, '[', '_');
                relevant_vars = strrep(relevant_vars, ']', '_');
                relevant_vars = strrep(relevant_vars, '/', '_');
                relevant_vars = strrep(relevant_vars, ' ', '');
                
                app.data = readtable([app.location, app.file]);
                waitbar(.2, w, "Reformatting file...");
                app.data = app.data(:,relevant_vars);

                waitbar(.4, w, "Writing new file...");
                % create new file with same name + ddt_
                app.file = strcat("ddt_", app.file);
                writetable(app.data, strcat(app.location, app.file));
            else
                app.data = readtable([app.location, app.file]);
            end
            
            %% Grab min & max lap and user inputted laps
            app.lap_min = app.data{1, "Dash_3_Lap_Number_None_"};
            app.lap_max = app.data{end, "Dash_3_Lap_Number_None_"};
            app.lapA = app.lapA_edit.Value;
            app.lapB = app.lapB_edit.Value;

            %% Calc fastest lap
            waitbar(.6, w, "Calculating lap data...");
            lap_times = app.data{:,"Dash_3_Lap_Time_s_"};
            % Find smallest non-zero lap time
            app.lapf_t = min(lap_times(lap_times>0));
            % Edge case: If lap time is always 0
            if isempty(app.lapf_t)
                app.lapf_t = 0;
            end
            % Grab row of lapf_t and use it to find lapf number
            app.lapf = app.data{lap_times==app.lapf_t, "Dash_3_Lap_Number_None_"}(1);

            %% Default lap A and B
            if (isempty(app.lapA))
                app.lapA = app.lapf;
                app.lapA_t = app.lapf_t;
            end
            if (isempty(app.lapB))
                app.lapB = -1;
                app.lapB_t = -1;
            end

            waitbar(1, w, "Finished");
            pause(0.5)
            close(w)

            % Error message if segmentation fault
            if (app.lapA < app.lap_min || app.lapA > app.lap_max || ...
                    (app.lapB < app.lap_min && app.lapB ~= -1) || app.lapB > app.lap_max)
                msgbox(["Error: Lap number out of bounds", ...
                        "Lap number must be between " + app.lap_min + " and " + app.lap_max], ...
                        "Error: Segmentation Fault")
                return
            end

            %% Lap data message box
            if app.lapB == -1
                lap2msg = "B: Lap ignored";
            else
                lap2msg = "B: Lap " + app.lapB + ", " + app.lapB_t + " seconds";
            end
            msgbox(["Fastest: Lap " + app.lapf + ", " + app.lapf_t + " seconds", ...
                    "A: Lap " + app.lapA + ", " + app.lapA_t + " seconds", ...
                    lap2msg], ...
                    "Lap Summary");
            
            %% Plot!
            

        end

        % Button pushed function: upload_log_btn
        function upload_file(app, event)
            dummy = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % create a dummy figure so that uigetfile doesn't minimize our GUI
            [f, l] = uigetfile('*.txt', "Select log file");
            delete(dummy); % delete the dummy figure
            if isequal(f, 0)
                return
            else
                app.file = f;
                app.location = l;
                %app.upload_log_btn.Text = app.file;
                app.file_lbl.Text = strcat(app.location, app.file);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MRacing2024DataVisualizerUIFigure = uifigure('Visible', 'off');
            app.MRacing2024DataVisualizerUIFigure.Name = 'MRacing 2024 Data Visualizer';
            app.run_visualizer_btn = uibutton(app.MRacing2024DataVisualizerUIFigure, 'push');
            app.run_visualizer_btn.ButtonPushedFcn = createCallbackFcn(app, @run_visualizer, true);
            app.run_visualizer_btn.Text = 'Run Visualizer';
            app.file_lbl = uilabel(app.MRacing2024DataVisualizerUIFigure);
            app.file_lbl.Tooltip = {''};
            app.file_lbl.Text = 'No file selected';
            app.reformat_file_chk = uicheckbox(app.MRacing2024DataVisualizerUIFigure);
            app.reformat_file_chk.Text = 'Reformat file';
            app.upload_log_btn = uibutton(app.MRacing2024DataVisualizerUIFigure, 'push');
            app.upload_log_btn.ButtonPushedFcn = createCallbackFcn(app, @upload_file, true);
            app.upload_log_btn.Text = 'Upload log file';
            app.mracing_img = uiimage(app.MRacing2024DataVisualizerUIFigure);
            app.mracing_img.ImageSource = fullfile(pathToMLAPP, 'app_resources', 'mracing_logo.png');
            app.title = uilabel(app.MRacing2024DataVisualizerUIFigure);
            app.title.Text = 'DRIVER DATA VISUALIZATION TOOL';
            app.Lap1Label = uilabel(app.MRacing2024DataVisualizerUIFigure);
            app.Lap1Label.Text = 'Lap A:';
            app.lapA_edit = uieditfield(app.MRacing2024DataVisualizerUIFigure, 'numeric');
            app.lapA_edit.RoundFractionalValues = 'on';
            app.lapA_edit.AllowEmpty = 'on';
            app.lapA_edit.Placeholder = 'Default fastest';
            app.lapA_edit.Value = [];
            app.LapBLabel = uilabel(app.MRacing2024DataVisualizerUIFigure);
            app.LapBLabel.Text = 'Lap B:';
            app.lapB_edit = uieditfield(app.MRacing2024DataVisualizerUIFigure, 'numeric');
            app.lapB_edit.RoundFractionalValues = 'on';
            app.lapB_edit.AllowEmpty = 'on';
            app.lapB_edit.Placeholder = 'Default ignore';
            app.lapB_edit.Value = [];
            app.version_lbl = uilabel(app.MRacing2024DataVisualizerUIFigure);
            app.version_lbl.Text = {'Askari Husaini'; 'V24.11.A'};
            app.log_file_hdr = uilabel(app.MRacing2024DataVisualizerUIFigure);
            app.log_file_hdr.Text = 'Log File & Lap(s) Analyzed';
            app.vars_hdr = uilabel(app.MRacing2024DataVisualizerUIFigure);
            app.vars_hdr.Text = 'Variables to Visualize';
            app.Hyperlink = uihyperlink(app.MRacing2024DataVisualizerUIFigure);
            app.Hyperlink.URL = 'https://github.com/askarihusaini/mracing24_ddt';
            app.Hyperlink.Text = 'Github repo';
            % Show the figure after all components are created
            app.MRacing2024DataVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRacing2024DataVisualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRacing2024DataVisualizerUIFigure)
        end
    end
end